classdef Submit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NoiseAmplitudeEditField        matlab.ui.control.NumericEditField
        NoiseAmplitudeEditFieldLabel   matlab.ui.control.Label
        NoiseButton                    matlab.ui.control.Button
        DSPProjectofAbdullahandMitwasLabel  matlab.ui.control.Label
        BandstopLowerCuttoffEditField  matlab.ui.control.NumericEditField
        BandstopLowerCuttoffEditFieldLabel  matlab.ui.control.Label
        BandstopUppercutoffEditField   matlab.ui.control.NumericEditField
        BandstopUppercutoffEditFieldLabel  matlab.ui.control.Label
        HigherCuttoffFrequencyHzEditField  matlab.ui.control.NumericEditField
        HigherCuttoffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        LowerCutoffFrequencyHzEditField  matlab.ui.control.NumericEditField
        LowerCutoffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        CuttoffFrequencyHzEditField    matlab.ui.control.NumericEditField
        CuttoffFrequencyHzEditField_2Label  matlab.ui.control.Label
        CuttoffFrequencyHzEditField_2  matlab.ui.control.NumericEditField
        CuttoffFrequencyHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyaudioreadHzEditField  matlab.ui.control.NumericEditField
        SamplingFrequencyaudioreadHzEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyHzEditField   matlab.ui.control.NumericEditField
        SamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        BandStopFilterButton           matlab.ui.control.Button
        BandPassFilterButton           matlab.ui.control.Button
        LowPassFilterButton            matlab.ui.control.Button
        HighPassFilterButton           matlab.ui.control.Button
        OriginalAudioButton            matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function y_highpass = applyHighPassFilter(app, y, Fs, cutoffFreq)
            [b, a] = butter(6, cutoffFreq / (Fs / 2), 'high');  % Create high-pass filter
            y_highpass = filter(b, a, y);  % Apply the high-pass filter
        end
        
        function y_lowpass = applyLowPassFilter(app, y, Fs, cutoffFreq)
            [b, a] = butter(6, cutoffFreq / (Fs / 2), 'low');  % Create low-pass filter
            y_lowpass = filter(b, a, y);  % Apply the low-pass filter
        end
        
        function y_bandpass = applyBandPassFilter(app, y, Fs, lowerCutoff, upperCutoff)
            [b, a] = butter(6, [lowerCutoff, upperCutoff] / (Fs / 2), 'bandpass');  % Create band-pass filter
            y_bandpass = filter(b, a, y);  % Apply the band-pass filter
        end

        
        function y_bandstop = applyBandStopFilter(app,y, Fs, lowerCutoff, upperCutoff)
            [b, a] = butter(6, [lowerCutoff, upperCutoff] / (Fs / 2), 'stop');  % Create band-stop filter
            y_bandstop = filter(b, a, y);  % Apply the band-stop filter
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OriginalAudioButton
        function OriginalAudioButtonPushed(app, event)
            userFs = app.SamplingFrequencyHzEditField.Value;
            
            % Load the audio file
            [y, Fs] = audioread('Rain + bird.mp3');
            
            % Resampling with User Sampling Rate
            y = resample(y, userFs, Fs);
            
            % Time axis in seconds
            time = (0:length(y)-1) / userFs;
            
            % Returning Original Sampling rate by audioread function
            app.SamplingFrequencyaudioreadHzEditField.Value = Fs;
            
            % Create a new figure for plotting the original waveform
            plot(app.UIAxes, time, y);
            app.UIAxes.Title.String = 'Original Audio Waveform';
            
            % Frequency Response Calculation
            n = length(y);                    % Number of samples
            frequencies = (0:n-1) * (userFs / n);   % Frequency axis in Hz
            magnitudeSpectrum = abs(fft(y));   % Magnitude of FFT
            
            % Plot Frequency Response
            plot(app.UIAxes2, frequencies(1:floor(n/2)), magnitudeSpectrum(1:floor(n/2)), 'b'); % Single-sided spectrum
            app.UIAxes2.Title.String = 'Frequency Response of Original Audio';
            app.UIAxes2.XLabel.String = 'Frequency (Hz)';
            app.UIAxes2.YLabel.String = 'Magnitude';
            
            % Play the original audio at the resampled rate
            sound(y, userFs);
        end

        % Button pushed function: LowPassFilterButton
        function LowPassFilterButtonPushed(app, event)
         % Taking Cutoff Frequency from User
        fclp = app.CuttoffFrequencyHzEditField.Value;
        
        % Taking Sampling Rate from User
        userFs = app.SamplingFrequencyHzEditField.Value;
        
        % Load the audio file
        [y, Fs] = audioread('Rain + bird.mp3');
        
        % Resampling with User Sampling Rate
        y = resample(y, userFs, Fs);
        
        % Time axis in seconds
        time = (0:length(y)-1) / userFs;

        noiseAmplitude = app.NoiseAmplitudeEditField.Value;
        noise = noiseAmplitude * randn(size(y));  % Gaussian noise
        
        % Add the noise to the original signal
        y_noisy = y + noise;
            
        % Apply low-pass filter with cutoff frequency of fclp
        y_lowpass = app.applyLowPassFilter(y_noisy, userFs, fclp);  % Apply low-pass filter
        
        % Create a new figure for plotting the waveform
        plot(app.UIAxes, time, y_lowpass, 'r');
        app.UIAxes.Title.String = 'Low-Pass Filtered Audio Waveform';
        
        % Frequency Response Calculation
        n = length(y_lowpass);                  % Number of samples
        frequencies = (0:n-1) * (userFs / n);   % Frequency axis in Hz
        magnitudeSpectrum = abs(fft(y_lowpass)); % Magnitude of FFT
        
        % Plot Frequency Response
        plot(app.UIAxes2, frequencies(1:floor(n/2)), magnitudeSpectrum(1:floor(n/2)), 'b'); % Single-sided spectrum
        app.UIAxes2.Title.String = 'Frequency Response of Low-Pass Filtered Signal';
        app.UIAxes2.XLabel.String = 'Frequency (Hz)';
        app.UIAxes2.YLabel.String = 'Magnitude';
        
        % Play the low-pass filtered audio
        sound(y_lowpass, userFs);

        end

        % Button pushed function: HighPassFilterButton
        function HighPassFilterButtonPushed(app, event)
            % Taking Cutoff Frequency from User
            fchp = app.CuttoffFrequencyHzEditField_2.Value;
            
            % Taking Sampling Rate from User
            userFs = app.SamplingFrequencyHzEditField.Value;
            
            % Load the audio file
            [y, Fs] = audioread('Rain + bird.mp3');
            
            % Resampling with User Sampling Rate
            y = resample(y, userFs, Fs);
            
            % Time axis in seconds
            time = (0:length(y)-1) / userFs;  

        noiseAmplitude = app.NoiseAmplitudeEditField.Value;
        noise = noiseAmplitude * randn(size(y));  % Gaussian noise
        
        % Add the noise to the original signal
        y_noisy = y + noise;
            
            % Apply high-pass filter with cutoff frequency of fchp
            y_highpass = app.applyHighPassFilter(y_noisy, userFs, fchp);  % Apply high-pass filter
            
            % Create a new figure for plotting the high-pass filtered waveform
            plot(app.UIAxes, time, y_highpass, 'r');
            app.UIAxes.Title.String = 'High-Pass Filtered Audio Waveform';
            
            % Frequency Response Calculation for high-pass filtered signal
            n = length(y_highpass);                    % Number of samples
            frequencies = (0:n-1) * (userFs / n);      % Frequency axis in Hz
            magnitudeSpectrum = abs(fft(y_highpass));  % Magnitude of FFT
            
            % Plot Frequency Response on UIAxes2
            plot(app.UIAxes2, frequencies(1:floor(n/2)), magnitudeSpectrum(1:floor(n/2)), 'b');  % Single-sided spectrum
            app.UIAxes2.Title.String = 'Frequency Response of High-Pass Filtered Signal';
            app.UIAxes2.XLabel.String = 'Frequency (Hz)';
            app.UIAxes2.YLabel.String = 'Magnitude';
            
            % Play the high-pass filtered audio
            sound(y_highpass, userFs);

        end

        % Button pushed function: BandPassFilterButton
        function BandPassFilterButtonPushed(app, event)
    % Taking Cutoff Frequencies from User
        lowerCutoff = app.LowerCutoffFrequencyHzEditField.Value;
        upperCutoff = app.HigherCuttoffFrequencyHzEditField.Value;
        
        % Taking Sampling Rate from User
        userFs = app.SamplingFrequencyHzEditField.Value;
        
        % Load the audio file
        [y, Fs] = audioread('Rain + bird.mp3');
        
        % Resampling with User Sampling Rate
        y = resample(y, userFs, Fs);
        
        % Time axis in seconds
        time = (0:length(y)-1) / userFs;

        noiseAmplitude = app.NoiseAmplitudeEditField.Value;
        noise = noiseAmplitude * randn(size(y));  % Gaussian noise
        
        % Add the noise to the original signal
        y_noisy = y + noise;
        
        % Apply band-pass filter
        y_bandpass = app.applyBandPassFilter(y_noisy, userFs, lowerCutoff, upperCutoff);
        
        % Create a new figure for plotting the band-pass filtered waveform
        plot(app.UIAxes, time, y_bandpass, 'g');
        app.UIAxes.Title.String = 'Band-Pass Filtered Audio Waveform';
        
        % Frequency Response Calculation for band-pass filtered signal
        n = length(y_bandpass);                       % Number of samples
        frequencies = (0:n-1) * (userFs / n);         % Frequency axis in Hz
        magnitudeSpectrum = abs(fft(y_bandpass));     % Magnitude of FFT
        
        % Plot Frequency Response on UIAxes2
        plot(app.UIAxes2, frequencies(1:floor(n/2)), magnitudeSpectrum(1:floor(n/2)), 'b');  % Single-sided spectrum
        app.UIAxes2.Title.String = 'Frequency Response of Band-Pass Filtered Signal';
        app.UIAxes2.XLabel.String = 'Frequency (Hz)';
        app.UIAxes2.YLabel.String = 'Magnitude';
        
        % Play the band-pass filtered audio
        sound(y_bandpass, userFs);
        end

        % Button pushed function: BandStopFilterButton
        function BandStopFilterButtonPushed(app, event)
    % Taking Cutoff Frequencies from User
        lowerCutoff = app.BandstopLowerCuttoffEditField.Value;
        upperCutoff = app.BandstopUppercutoffEditField.Value;
        
        % Taking Sampling Rate from User
        userFs = app.SamplingFrequencyHzEditField.Value;
        
        % Load the audio file
        [y, Fs] = audioread('Rain + bird.mp3');
        
        % Resampling with User Sampling Rate
        y = resample(y, userFs, Fs);

        noiseAmplitude = app.NoiseAmplitudeEditField.Value;
        noise = noiseAmplitude * randn(size(y));  % Gaussian noise
        
        % Add the noise to the original signal
        y_noisy = y + noise;
        
        % Apply band-stop filter
        y_bandstop = app.applyBandStopFilter(y_noisy, userFs, lowerCutoff, upperCutoff);
        
        % Time axis in seconds
        time = (0:length(y)-1) / userFs;
        
        % Create a new figure for plotting the band-stop filtered waveform
        plot(app.UIAxes, time, y_bandstop, 'b');
        app.UIAxes.Title.String = 'Band-Stop Filtered Audio Waveform';
        
        % Frequency Response Calculation for band-stop filtered signal
        n = length(y_bandstop);                        % Number of samples
        frequencies = (0:n-1) * (userFs / n);          % Frequency axis in Hz
        magnitudeSpectrum = abs(fft(y_bandstop));      % Magnitude of FFT
        
        % Plot Frequency Response on UIAxes2
        plot(app.UIAxes2, frequencies(1:floor(n/2)), magnitudeSpectrum(1:floor(n/2)), 'r');  % Single-sided spectrum
        app.UIAxes2.Title.String = 'Frequency Response of Band-Stop Filtered Signal';
        app.UIAxes2.XLabel.String = 'Frequency (Hz)';
        app.UIAxes2.YLabel.String = 'Magnitude';
        
        % Play the band-stop filtered audio
        sound(y_bandstop, userFs);

        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            % Taking Sampling Rate from User
    userFs = app.SamplingFrequencyHzEditField.Value;
    
    % Load the original audio file
    [y, Fs] = audioread('Rain + bird.mp3');
    
    % Resample the audio to the user-defined sampling rate
    y = resample(y, userFs, Fs);
    
    % Time axis for plotting the signal
    time = (0:length(y)-1) / userFs;
    
    % Generate random noise (Gaussian white noise)
    noiseAmplitude = app.NoiseAmplitudeEditField.Value;
    noise = noiseAmplitude * randn(size(y));  % Gaussian noise
    
    % Add the noise to the original signal
    y_noisy = y + noise;

    % Create a new figure for plotting the noisy signal's waveform
    plot(app.UIAxes, time, y_noisy, 'r');  
    app.UIAxes.Title.String = 'Noisy Signal Waveform';
    app.UIAxes.XLabel.String = 'Time (s)';
    app.UIAxes.YLabel.String = 'Amplitude';
    
    % Frequency Response Calculation for the noisy signal
    n = length(y_noisy);                        % Number of samples
    frequencies = (0:n-1) * (userFs / n);       % Frequency axis in Hz
    magnitudeSpectrum = abs(fft(y_noisy));      % Magnitude of FFT
    
    % Plot Frequency Response of the noisy signal
    plot(app.UIAxes2, frequencies(1:floor(n/2)), magnitudeSpectrum(1:floor(n/2)), 'b'); 
    app.UIAxes2.Title.String = 'Magnitude Response of Noisy Signal';
    app.UIAxes2.XLabel.String = 'Frequency (Hz)';
    app.UIAxes2.YLabel.String = 'Magnitude';
    
    % Play the noisy audio
    sound(y_noisy, userFs);

    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Magnitude Responce')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.OriginalAudioButton = uibutton(app.UIFigure, 'push');
            app.OriginalAudioButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalAudioButtonPushed, true);
            app.OriginalAudioButton.Text = 'Original Audio';
            app.HighPassFilterButton = uibutton(app.UIFigure, 'push');
            app.HighPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @HighPassFilterButtonPushed, true);
            app.HighPassFilterButton.Text = 'High-Pass Filter';
            app.LowPassFilterButton = uibutton(app.UIFigure, 'push');
            app.LowPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @LowPassFilterButtonPushed, true);
            app.LowPassFilterButton.Text = 'Low-Pass Filter';
            app.BandPassFilterButton = uibutton(app.UIFigure, 'push');
            app.BandPassFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandPassFilterButtonPushed, true);
            app.BandPassFilterButton.Text = 'Band-Pass Filter';
            app.BandStopFilterButton = uibutton(app.UIFigure, 'push');
            app.BandStopFilterButton.ButtonPushedFcn = createCallbackFcn(app, @BandStopFilterButtonPushed, true);
            app.BandStopFilterButton.Text = 'Band-Stop Filter';
            app.SamplingFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyHzEditFieldLabel.Text = 'Sampling Frequency (Hz)';
            app.SamplingFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingFrequencyHzEditField.Value = 44100;
            app.SamplingFrequencyaudioreadHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingFrequencyaudioreadHzEditFieldLabel.Text = 'Sampling Frequency audioread (Hz)';
            app.SamplingFrequencyaudioreadHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CuttoffFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.CuttoffFrequencyHzEditFieldLabel.Text = 'Cuttoff Frequency (Hz)';
            app.CuttoffFrequencyHzEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CuttoffFrequencyHzEditField_2Label = uilabel(app.UIFigure);
            app.CuttoffFrequencyHzEditField_2Label.Text = 'Cuttoff Frequency (Hz)';
            app.CuttoffFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerCutoffFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.LowerCutoffFrequencyHzEditFieldLabel.Text = 'Lower Cutoff Frequency (Hz)';
            app.LowerCutoffFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.HigherCuttoffFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.HigherCuttoffFrequencyHzEditFieldLabel.Text = 'Higher-Cuttoff Frequency (Hz)';
            app.HigherCuttoffFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandstopUppercutoffEditFieldLabel = uilabel(app.UIFigure);
            app.BandstopUppercutoffEditFieldLabel.Text = 'Bandstop Upper cutoff';
            app.BandstopUppercutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandstopLowerCuttoffEditFieldLabel = uilabel(app.UIFigure);
            app.BandstopLowerCuttoffEditFieldLabel.Text = 'Bandstop Lower Cuttoff';
            app.BandstopLowerCuttoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.DSPProjectofAbdullahandMitwasLabel = uilabel(app.UIFigure);
            app.DSPProjectofAbdullahandMitwasLabel.Text = 'DSP Project of Abdullah and Mitwas';
            app.NoiseButton = uibutton(app.UIFigure, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Text = 'Noise';
            app.NoiseAmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseAmplitudeEditFieldLabel.Text = 'Noise Amplitude';
            app.NoiseAmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Submit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end